classdef MyCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        Panel_2       matlab.ui.container.Panel
        Button_7      matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_3      matlab.ui.control.Button
        Button_6      matlab.ui.control.Button
        Button_9      matlab.ui.control.Button
        Button_2      matlab.ui.control.Button
        Button_1      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        Button_C      matlab.ui.control.Button
        Button_0      matlab.ui.control.Button
        PointButton   matlab.ui.control.Button
        Button_minus  matlab.ui.control.Button
        Button_mult   matlab.ui.control.Button
        Button_divis  matlab.ui.control.Button
        Button_rez    matlab.ui.control.Button
        Button_plus   matlab.ui.control.Button
        Panel_3       matlab.ui.container.Panel
        Label_Result  matlab.ui.control.Label
    end

    
    properties (Access = private)
        PointNumber;
        enablePoint = 0;
        integerNumber = 0;
        first;
        second;
        sign; %- + * /
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '1'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '1'];
            end 
            app.Label_Result.Text = num2str(str2num(result));%convert to number and back,Output without 0 at the beginning
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '2'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '2'];
            end 
            app.Label_Result.Text = num2str(str2num(result));
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '3'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '3'];
            end 
            app.Label_Result.Text = num2str(str2num(result));
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '4'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '4'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '5'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '5'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '6'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '6'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '7'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '7'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '8'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '8'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '9'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '9'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            result = '0';
            if app.enablePoint == 1 
                app.PointNumber = [app.PointNumber '0'];
                result = [num2str(app.integerNumber) '.' app.PointNumber];
            else 
                result = [app.Label_Result.Text '0'];
            end 
            app.Label_Result.Text = num2str(str2num(result)); 
        end

        % Button pushed function: PointButton
        function PointButtonPushed(app, event)
            app.enablePoint = 1;
            app.integerNumber = str2num(app.Label_Result.Text);
        end

        % Button pushed function: Button_C
        function Button_CPushed(app, event)
            app.Label_Result.Text = '0';
            app.integerNumber = 0;
            app.PointNumber = '';
            app.enablePoint = '0';
        end

        % Button pushed function: Button_plus
        function Button_plusPushed(app, event)
            app.sign = '+';
            app.first = str2num(app.Label_Result.Text);
            app.Label_Result.Text = '0';
            app.integerNumber = 0;
            app.PointNumber = '';
            app.enablePoint = 0;
        end

        % Button pushed function: Button_rez
        function Button_rezPushed(app, event)
            app.second = str2num(app.Label_Result.Text);
            if app.sign == '+'
                app.Label_Result.Text = num2str(app.first + app.second);
            elseif app.sign == '-'
                app.Label_Result.Text = num2str(app.first - app.second);
            elseif app.sign == '*'
                app.Label_Result.Text = num2str(app.first * app.second);
            elseif app.sign == '/'
                app.Label_Result.Text = num2str(app.first / app.second);
            end
        end

        % Button pushed function: Button_minus
        function Button_minusPushed(app, event)
%             app.second = str2num(app.Label_Result.Text);
            app.sign = '-';
            app.first = str2num(app.Label_Result.Text);
            app.Label_Result.Text = '0';
            app.integerNumber = 0;
            app.PointNumber = '';
            app.enablePoint = 0;
        end

        % Button pushed function: Button_mult
        function Button_multPushed(app, event)
%             app.second = str2num(app.Label_Result.Text);
            app.sign = '*';
            app.first = str2num(app.Label_Result.Text);
            app.Label_Result.Text = '0';
            app.integerNumber = 0;
            app.PointNumber = '';
            app.enablePoint = 0;
        end

        % Button pushed function: Button_divis
        function Button_divisPushed(app, event)
            app.sign = '/';
            app.first = str2num(app.Label_Result.Text);
            app.Label_Result.Text = '0';
            app.integerNumber = 0;
            app.PointNumber = '';
            app.enablePoint = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.Button_7 = uibutton(app.Panel_2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = {'7'; ''};
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = {'8'; ''};
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = {'5'; ''};
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = {'3'; ''};
            app.Button_6 = uibutton(app.Panel_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = {'6'; ''};
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = {'9'; ''};
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'2'; ''};
            app.Button_1 = uibutton(app.Panel_2, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = {'1'; ''};
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = {'4'; ''};
            app.Button_C = uibutton(app.Panel_2, 'push');
            app.Button_C.ButtonPushedFcn = createCallbackFcn(app, @Button_CPushed, true);
            app.Button_C.Text = 'C';
            app.Button_0 = uibutton(app.Panel_2, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.PointButton = uibutton(app.Panel_2, 'push');
            app.PointButton.ButtonPushedFcn = createCallbackFcn(app, @PointButtonPushed, true);
            app.PointButton.Text = '.';
            app.Button_minus = uibutton(app.Panel_2, 'push');
            app.Button_minus.ButtonPushedFcn = createCallbackFcn(app, @Button_minusPushed, true);
            app.Button_minus.Text = '-';
            app.Button_mult = uibutton(app.Panel_2, 'push');
            app.Button_mult.ButtonPushedFcn = createCallbackFcn(app, @Button_multPushed, true);
            app.Button_mult.Text = '*';
            app.Button_divis = uibutton(app.Panel_2, 'push');
            app.Button_divis.ButtonPushedFcn = createCallbackFcn(app, @Button_divisPushed, true);
            app.Button_divis.Text = '/';
            app.Button_rez = uibutton(app.Panel_2, 'push');
            app.Button_rez.ButtonPushedFcn = createCallbackFcn(app, @Button_rezPushed, true);
            app.Button_rez.Text = '=';
            app.Button_plus = uibutton(app.Panel_2, 'push');
            app.Button_plus.ButtonPushedFcn = createCallbackFcn(app, @Button_plusPushed, true);
            app.Button_plus.Text = '+';
            app.Panel_3 = uipanel(app.Panel);
            app.Label_Result = uilabel(app.Panel_3);
            app.Label_Result.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end